classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MoveLButton                    matlab.ui.control.Button
        MoveJButton                    matlab.ui.control.Button
        openEXCELsheetxlsxButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        plottrajectoryinfoCheckBox     matlab.ui.control.CheckBox
        Label                          matlab.ui.control.Label
        defaultzvalue300mmLabel_2      matlab.ui.control.Label
        defaultzvalue300mmLabel_3      matlab.ui.control.Label
        plottrajectoryprofileCheckBox  matlab.ui.control.CheckBox
        DropDown                       matlab.ui.control.DropDown
        methodfordeterminingthelineartrajectoryLabel  matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        group 
        kin
        waypoints
        trajectory
        trajGen
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.group, app.kin] = setup();
            app.waypoints = read_file();
            app.UITable.Data = app.waypoints;
            
            % Set Editable
%             ncols = size(app.UITable.Data, 2);
%             app.UITable.ColumnEditable = true(1, ncols); % All columns editable
%             app.UITable.ColumnEditable(1) = false; % Column 1 (index) non-editable
        end

        % Button pushed function: MoveLButton
        function MoveLButtonPushed(app, event)
            
            plot_traj = app.plottrajectoryinfoCheckBox.Value;
            plot_inter = app.plottrajectoryprofileCheckBox.Value;
            val = app.DropDown.Value;
            
            switch val
                case 'linearly spaced vector'
                    [app.trajectory, app.trajGen] = traj_linspace(app.kin, app.waypoints, plot_traj, plot_inter);
                case '1-D data interpolation'
                    [app.trajectory, app.trajGen] = traj_interp1(app.kin, app.waypoints, plot_traj, plot_inter);
                case 'cubic spline data interpolation'
                    [app.trajectory, app.trajGen] = traj_cubic_spline(app.kin, app.waypoints, plot_traj, plot_inter);
            end
            
            val2 = app.DropDown_2.Value;
            
            switch val2
                case 'blocking trajectory'
                    traj_exe_blocking( app.group, app.trajectory, app.trajGen );
                case 'non blocking trajectory'
                    traj_exe_non_blocking( app.group, app.trajectory );
            end
        end

        % Button pushed function: openEXCELsheetxlsxButton
        function openEXCELsheetxlsxButtonPushed(app, event)
            open_file();
        end

        % Button pushed function: MoveJButton
        function MoveJButtonPushed(app, event)
            plot_traj = app.plottrajectoryinfoCheckBox.Value;
            [app.trajectory, app.trajGen] = traj_joi(app.kin, app.waypoints, plot_traj);
            
            val2 = app.DropDown_2.Value;
            
            switch val2
                case 'blocking trajectory'
                    traj_exe_blocking( app.group, app.trajectory, app.trajGen );
                case 'non blocking trajectory'
                    traj_exe_non_blocking( app.group, app.trajectory );
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MoveLButton = uibutton(app.UIFigure, 'push');
            app.MoveLButton.ButtonPushedFcn = createCallbackFcn(app, @MoveLButtonPushed, true);
            app.MoveLButton.Text = 'MoveL';
            app.MoveJButton = uibutton(app.UIFigure, 'push');
            app.MoveJButton.ButtonPushedFcn = createCallbackFcn(app, @MoveJButtonPushed, true);
            app.MoveJButton.Text = 'MoveJ';
            app.openEXCELsheetxlsxButton = uibutton(app.UIFigure, 'push');
            app.openEXCELsheetxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @openEXCELsheetxlsxButtonPushed, true);
            app.openEXCELsheetxlsxButton.Text = 'open EXCEL sheet (*.xlsx)';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'index'; 'x [mm]'; 'y [mm]'; 'z [mm]'};
            app.UITable.RowName = {};
            app.plottrajectoryinfoCheckBox = uicheckbox(app.UIFigure);
            app.plottrajectoryinfoCheckBox.Text = 'plot trajectory info';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.defaultzvalue300mmLabel_2 = uilabel(app.UIFigure);
            app.defaultzvalue300mmLabel_2.Text = 'move linear in x-y frame (default z-value: -300 [mm]) ';
            app.defaultzvalue300mmLabel_3 = uilabel(app.UIFigure);
            app.defaultzvalue300mmLabel_3.Text = {'move by joint movement in x-y-z frame '; 'ATTENTION: only use when desk not attached!'};
            app.plottrajectoryprofileCheckBox = uicheckbox(app.UIFigure);
            app.plottrajectoryprofileCheckBox.Text = 'plot trajectory profile';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'linearly spaced vector', '1-D data interpolation', 'cubic spline data interpolation'};
            app.DropDown.Value = 'linearly spaced vector';
            app.methodfordeterminingthelineartrajectoryLabel = uilabel(app.UIFigure);
            app.methodfordeterminingthelineartrajectoryLabel.Text = 'method for determining the linear trajectory:';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'blocking trajectory', 'non blocking trajectory'};
            app.DropDown_2.Value = 'blocking trajectory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end